classdef AlgorithmGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SelectionSortButton            matlab.ui.control.Button
        InsertionSortButton            matlab.ui.control.Button
        QuickSortButton                matlab.ui.control.Button
        MergeSortButton                matlab.ui.control.Button
        RadixSortButton                matlab.ui.control.Button
        BubbleSortButton               matlab.ui.control.Button
        SortingAlgorithmsLabel         matlab.ui.control.Label
        UnsortedVector5476329821Label  matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: SelectionSortButton
        function SelectionSortButtonPushed(app, event)
            function x = selectionsort(x)
                
                n = length(x);
                for j = 1:(n - 1)
                    imin = j;
                    for i = (j + 1):n
                        if (x(i) < x(imin))
                            imin = i;
                        end
                    end
                    
                    if (imin ~= j)
                        x = swap(x,imin,j);
                    end
                end
            end
            function x = swap(x,i,j)
                
                val = x(i);
                x(i) = x(j);
                x(j) = val;
            end
            
            result = num2str(selectionsort([54,76,32,98,21]));
            result2 = "[" + result + "]";
            fig = uifigure;
            lbl = uilabel(fig);
            lbl.FontSize = 30;
            lbl.Position(3:4) = [500 100];
            lbl.Text = "Selection Sort: "+result2;
        end

        % Button pushed function: InsertionSortButton
        function InsertionSortButtonPushed(app, event)
            function x = insertionsort(x)
                n = length(x);
                for j = 2:n
                    center = x(j);
                    i = j;
                    while ((i > 1) && (x(i - 1) > center))
                        x(i) = x(i - 1);
                        i = i - 1;
                    end
                    x(i) = center;
                end
            end
            
            result = num2str(insertionsort([54,76,32,98,21]));
            result2 = "[" + result + "]";
            fig = uifigure;
            lbl = uilabel(fig);
            lbl.FontSize = 30;
            lbl.Position(3:4) = [500 100];
            lbl.Text = "Insertion Sort: "+result2;
            
        end

        % Button pushed function: QuickSortButton
        function QuickSortButtonPushed(app, event)
            function x = quicksort(x)
                kk = 15; % Insertion sort threshold, kk >= 1
                % Quicksort
                n = length(x);
                x = quicksorti(x,1,n,kk);
            end
            function x = quicksorti(x,ll,uu,kk)
                
                [x mm] = partition(x,ll,uu);
                % Divide-and-conquer
                if ((mm - ll) <= kk)
                    % Sort x(ll:(mm - 1)) via insertion sort
                    x = insertionsorti(x,ll,mm - 1);
                else
                    % Sort x(ll:(mm - 1)) via quick sort
                    x = quicksorti(x,ll,mm - 1,kk);
                end
                if ((uu - mm) <= kk)
                    % Sort x((mm + 1):uu) via insertion sort
                    x = insertionsorti(x,mm + 1,uu);
                else
                    % Sort x((mm + 1):uu) via quick sort
                    x = quicksorti(x,mm + 1,uu,kk);
                end
            end
            function [x mm] = partition(x,ll,uu)
                
                pp = medianofthree(x,ll,uu);
                
                x = swap(x,ll,pp);
                mm = ll;
                for j = (ll + 1):uu
                    if (x(j) < x(ll))
                        mm = mm + 1;
                        x = swap(x,mm,j);
                    end
                end
                x = swap(x,ll,mm);
            end
            function pp = medianofthree(x,ll,uu)
                mm = ll + floor((uu - ll) / 2);
                if (x(ll) <= x(mm))
                    if (x(uu) >= x(mm))
                        pp = mm;
                    elseif (x(uu) >= x(ll))
                        pp = uu;
                    else
                        pp = ll;
                    end
                else
                    if (x(uu) >= x(ll))
                        pp = ll;
                    elseif (x(uu) >= x(mm))
                        pp = uu;
                    else
                        pp = mm;
                    end
                end
            end
            function x = insertionsorti(x,ll,uu)
                
                for j = (ll + 1):uu
                    pivot = x(j);
                    i = j;
                    while ((i > ll) && (x(i - 1) > pivot))
                        x(i) = x(i - 1);
                        i = i - 1;
                    end
                    x(i) = pivot;
                end
            end
            function x = swap(x,i,j)
                val = x(i);
                x(i) = x(j);
                x(j) = val;
            end
            
            
            result = num2str(quicksort([54,76,32,98,21]));
            result2 = "[" + result + "]";
            fig = uifigure;
            lbl = uilabel(fig);
            lbl.FontSize = 30;
            lbl.Position(3:4) = [500 100];
            lbl.Text = "Quick Sort: "+result2;
        end

        % Button pushed function: MergeSortButton
        function MergeSortButtonPushed(app, event)
            function x = mergesort(x)
                kk = 15; % Insertion sort threshold, k >= 1
                % Mergesort
                n = length(x);
                x = mergesorti(x,1,n,kk);
            end
            function x = mergesorti(x,ll,uu,kk)
                % Sort x(ll:uu) via merge sort
                % Note: In practice, x xhould be passed by reference
                % Compute center index
                mm = floor((ll + uu) / 2);
                % Divide...
                if ((mm + 1 - ll) <= kk)
                    % Sort x(ll:mm) via insertion sort
                    x = insertionsorti(x,ll,mm);
                else
                    % Sort x(ll:mm) via insertion sort
                    x = mergesorti(x,ll,mm,kk);
                end
                if ((uu - mm) <= kk)
                    % Sort x((mm + 1):uu) via insertion sort
                    x = insertionsorti(x,mm + 1,uu);
                else
                    % Sort x((mm + 1):uu) via merge sort
                    x = mergesorti(x,mm + 1,uu,kk);
                end
                % ... and conquer
                % Combine sorted arrays x(ll:mm) and x((mm + 1):uu)
                x = merge(x,ll,mm,uu);
            end
            function x = insertionsorti(x,ll,uu)
                % Sort x(ll:uu) via insertion sort
                % Note: In practice, x xhould be passed by reference
                for j = (ll + 1):uu
                    pivot = x(j);
                    i = j;
                    while ((i > ll) && (x(i - 1) > pivot))
                        x(i) = x(i - 1);
                        i = i - 1;
                    end
                    x(i) = pivot;
                end
            end
            function x = merge(x,ll,mm,uu)
                % Combine sorted arrays x(ll:mm) and x((mm + 1):uu)
                % Note: In practice, x should be passed by reference
                % Note: In practice, use memcpy() or similar
                L = x(ll:mm);
                % Combine sorted arrays
                i = 1;
                j = mm + 1;
                k = ll;
                while ((k < j) && (j <= uu))
                    if (L(i) <= x(j))
                        x(k) = L(i);
                        i = i + 1;
                    else
                        x(k) = x(j);
                        j = j + 1;
                    end
                    k = k + 1;
                end
                % Note: In practice, use memcpy() or similar
                x(k:(j - 1)) = L(i:(i + j - k - 1));
            end
            
            result = num2str(mergesort([54,76,32,98,21]));
            result2 = "[" + result + "]";
            fig = uifigure;
            lbl = uilabel(fig);
            lbl.FontSize = 30;
            lbl.Position(3:4) = [500 100];
            lbl.Text = "Merge Sort: "+result2;
        end

        % Button pushed function: RadixSortButton
        function RadixSortButtonPushed(app, event)
            function x = radixsort(x,r)
                if (nargin == 1)
                    % Default radix
                    r = 16;
                end
                % Convert x to radix-r array
                dims = size(x);
                x = x(:);
                [rx n d] = array2baser(x,r); % rx is an (n x d) matrix
                rx = rx + 1;
                
                idx = (1:n)';
                for i = 0:(d - 2)
                    % Sort by ith least-significant digit
                    idx = idx(countingsort(rx(idx,d - i),n,r));
                end
                pos = (rx(idx,1) >= 1); % nonnegative numbers
                x = [x(flipud(idx(logical(1 - pos))));x(idx(pos))]; % put negative numbers at beginning
                x = reshape(x,dims);
            end
            function idx = countingsort(x,n,r)
                
                C = zeros(r,1);
                for j = 1:n
                    C(x(j)) = C(x(j)) + 1;
                end
                % Convert to cumulative histogram
                for i = 2:r
                    C(i) = C(i) + C(i - 1);
                end
                % Return sorted indices
                idx = nan(n,1);
                for j = n:-1:1
                    idx(C(x(j))) = j;
                    C(x(j)) = C(x(j)) - 1;
                end
            end
            function [rx n d] = array2baser(array,r)
                
                if isinteger(array)
                    % Integer array
                    switch class(array)
                        case 'int8'
                            b = 7;   % integer bits
                        case 'uint8'
                            b = 8;   % integer bits
                        case 'int16'
                            b = 15;  % integer bits
                        case 'uint16'
                            b = 16;  % integer bits
                        case 'int32'
                            b = 31;  % integer bits
                        case 'uint32'
                            b = 32;  % integer bits
                        case 'int64'
                            b = 63;  % integer bits
                        case 'uint64'
                            b = 64;  % integer bits
                    end
                    d = ceil(b / log2(r)) + 1; % number of base-r digits
                    
                    % Convert numbers to base-r
                    n = length(array);
                    rx = zeros(n,d);
                    for i = 1:n
                        % Convert array(i) to base-r
                        rx(i,:) = [sign(array(i)) int2baser(abs(array(i)),r,d - 1)];
                    end
                elseif isfloat(array)
                    % Floating point array
                    switch class(array)
                        case 'single'
                            eb = 8;  % exponent bits
                            fb = 23; % fraction bits
                        case 'double'
                            eb = 11; % exponent bits
                            fb = 52; % fraction bits
                    end
                    de = ceil(eb / log2(r)); % number of base-r exponent digits
                    df = ceil(fb / log2(r)); % number of base-r fractional digits
                    d = de + df + 1; % total number of base-r digits
                    
                    % Convert numbers to base-r
                    n = length(array);
                    rx = zeros(n,d);
                    for i = 1:n
                        % Convert array(i) to base-r
                        rx(i,:) = float2baser(array(i),r,eb,de,d);
                    end
                else
                    error('Array must be floating point or integer');
                end
            end
            function hx = float2baser(x,r,eb,de,d)
                
                if (abs(x) < realmin(class(x)))
                    e = 0;
                    f = r * abs(x) / realmin(class(x));
                else
                    [f e] = log2(abs(x));    % abs(x) = f * 2^e, with 0.5 <= f < 1
                    bias = 2^(eb - 1) - 2;
                    e = e + bias;            % e >= 0
                    f = r * (2 * (f - 0.5)); % f in [0,r]
                end
                
                hx = zeros(1,d);
                hx(1) = sign(x);
                hx(2:(de + 1)) = int2baser(e,r,de);
                for j = (de + 2):d
                    hx(j) = floor(f);
                    f = r * rem(f,1);
                end
            end
            function rx = int2baser(x,r,d)
                rx = zeros(1,d);
                x = double(x);
                rx(d) = rem(x,r);
                while ((x > 0) && (d > 1))
                    d = d - 1;
                    x = floor(x / r);
                    rx(d) = rem(x,r);
                end
            end
            
            
            result = num2str(radixsort([54,76,32,98,21]));
            result2 = "[" + result + "]";
            fig = uifigure;
            lbl = uilabel(fig);
            lbl.FontSize = 30;
            lbl.Position(3:4) = [500 100];
            lbl.Text = "Radix Sort: "+result2;
        end

        % Button pushed function: BubbleSortButton
        function BubbleSortButtonPushed(app, event)
            function x = bubblesort(x)
                
                n = length(x);
                while (n > 0)
                    nnew = 0;
                    for i = 2:n
                        if (x(i) < x(i - 1))
                            x = swap(x,i,i - 1);
                            nnew = i;
                        end
                    end
                    n = nnew;
                end
            end
            function x = swap(x,i,j)
                val = x(i);
                x(i) = x(j);
                x(j) = val;
            end


            result = num2str(bubblesort([54,76,32,98,21]));
            result2 = "[" + result + "]";
            fig = uifigure;
            lbl = uilabel(fig);
            lbl.FontSize = 30;
            lbl.Position(3:4) = [500 100];
            lbl.Text = "Bubble Sort: "+result2;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectionSortButton = uibutton(app.UIFigure, 'push');
            app.SelectionSortButton.ButtonPushedFcn = createCallbackFcn(app, @SelectionSortButtonPushed, true);
            app.SelectionSortButton.Text = 'Selection Sort';
            app.InsertionSortButton = uibutton(app.UIFigure, 'push');
            app.InsertionSortButton.ButtonPushedFcn = createCallbackFcn(app, @InsertionSortButtonPushed, true);
            app.InsertionSortButton.Text = 'Insertion Sort';
            app.QuickSortButton = uibutton(app.UIFigure, 'push');
            app.QuickSortButton.ButtonPushedFcn = createCallbackFcn(app, @QuickSortButtonPushed, true);
            app.QuickSortButton.Text = 'Quick Sort';
            app.MergeSortButton = uibutton(app.UIFigure, 'push');
            app.MergeSortButton.ButtonPushedFcn = createCallbackFcn(app, @MergeSortButtonPushed, true);
            app.MergeSortButton.Text = 'Merge Sort';
            app.RadixSortButton = uibutton(app.UIFigure, 'push');
            app.RadixSortButton.ButtonPushedFcn = createCallbackFcn(app, @RadixSortButtonPushed, true);
            app.RadixSortButton.Text = 'Radix Sort';
            app.BubbleSortButton = uibutton(app.UIFigure, 'push');
            app.BubbleSortButton.ButtonPushedFcn = createCallbackFcn(app, @BubbleSortButtonPushed, true);
            app.BubbleSortButton.Text = 'Bubble Sort';
            app.SortingAlgorithmsLabel = uilabel(app.UIFigure);
            app.SortingAlgorithmsLabel.Text = 'Sorting Algorithms';
            app.UnsortedVector5476329821Label = uilabel(app.UIFigure);
            app.UnsortedVector5476329821Label.Text = 'Unsorted Vector: [54,76,32,98,21]';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AlgorithmGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end